classdef BladeDesignerBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesigner                  matlab.ui.Figure
        TurbomachineryBladeDesignerLabel  matlab.ui.control.Label
        Selection                      matlab.ui.container.TabGroup
        Turbine                        matlab.ui.container.Tab
        CamberAxes_T                   matlab.ui.control.UIAxes
        ThicknessAxes_T                matlab.ui.control.UIAxes
        BladeAxes_T                    matlab.ui.control.UIAxes
        InletMetalAngleSlider_T        matlab.ui.control.Slider
        OutletMetalAngleSliderLabel_T  matlab.ui.control.Label
        OutletMetalAngleSlider_T       matlab.ui.control.Slider
        InletMetalAngleSliderLabel_T   matlab.ui.control.Label
        StaggerAngleSlider_T           matlab.ui.control.Slider
        StaggerAngleSliderLabel_T      matlab.ui.control.Label
        ChoosebladethicknessprofileLabel_T  matlab.ui.control.Label
        LogdetailsLabel_T              matlab.ui.control.Label
        ThicknessOptions_T             matlab.ui.control.ListBox
        InletMetalAngleValue_T         matlab.ui.control.EditField
        OutletMetalAngleValue_T        matlab.ui.control.EditField
        StaggerAngleValue_T            matlab.ui.control.EditField
        AddthickprofileButton_T        matlab.ui.control.Button
        CamberLineAnglesLabel_T        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Camber1Value_T                 matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        Camber2Value_T                 matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        CamberAngleValue_T             matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        DeleteprofileButton_T          matlab.ui.control.Button
        IncreaseordecreasethemaximumthicknessLabel_T  matlab.ui.control.Label
        MaximumThicknessoftheProfileLabel  matlab.ui.control.Label
        MaximumThicknessProfile_T      matlab.ui.control.NumericEditField
        MaxThicknessSlider_T           matlab.ui.control.Slider
        MaxThicknessValue_T            matlab.ui.control.NumericEditField
        MaxThicknessSliderValue_T      matlab.ui.control.EditField
        Log_T                          matlab.ui.control.TextArea
        MisesFileEditFieldLabel        matlab.ui.control.Label
        MisesFileInputs_T              matlab.ui.control.EditField
        HelpButton_T                   matlab.ui.control.Button
        SaveProfileButton_T            matlab.ui.control.Button
        CompressorTab                  matlab.ui.container.Tab
        CamberAxes_C                   matlab.ui.control.UIAxes
        ThicknessAxes_C                matlab.ui.control.UIAxes
        BladeAxes_C                    matlab.ui.control.UIAxes
        InletMetalAngleSlider_C        matlab.ui.control.Slider
        OutletMetalAngleSliderLabel_C  matlab.ui.control.Label
        OutletMetalAngleSlider_C       matlab.ui.control.Slider
        InletMetalAngleSliderLabel_C   matlab.ui.control.Label
        StaggerAngleSlider_C           matlab.ui.control.Slider
        StaggerAngleSliderLabel_C      matlab.ui.control.Label
        InletMetalAngleValue_C         matlab.ui.control.EditField
        OutletMetalAngleValue_C        matlab.ui.control.EditField
        StaggerAngleValue_C            matlab.ui.control.EditField
        CamberLineAnglesLabel_C        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Camber1Value_C                 matlab.ui.control.EditField
        Label_7                        matlab.ui.control.Label
        Camber2Value_C                 matlab.ui.control.EditField
        Label_8                        matlab.ui.control.Label
        CamberAngleValue_C             matlab.ui.control.EditField
        ChoosebladethicknessprofileLabel_C  matlab.ui.control.Label
        ThicknessOptions_C             matlab.ui.control.ListBox
        AddthickprofileButton_C        matlab.ui.control.Button
        DeleteprofileButton_C          matlab.ui.control.Button
        IncreaseordecreasethemaximumthicknessLabel_C  matlab.ui.control.Label
        MaximumThicknessoftheProfileLabel_C  matlab.ui.control.Label
        MaximumThicknessProfile_C      matlab.ui.control.NumericEditField
        MaxThicknessSlider_C           matlab.ui.control.Slider
        MaxThicknessValue_C            matlab.ui.control.NumericEditField
        MaxThicknessSliderValue_C      matlab.ui.control.EditField
        LogdetailsLabel_C              matlab.ui.control.Label
        Log_C                          matlab.ui.control.TextArea
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        HelpButton_C                   matlab.ui.control.Button
        SaveProfileButton_C            matlab.ui.control.Button
        MisesFileEditField_2Label      matlab.ui.control.Label
        MisesFileInputs_C              matlab.ui.control.EditField
        ManualTab                      matlab.ui.container.Tab
        LeadingEdgeParametersLabel     matlab.ui.control.Label
        SemimajoraxisSliderLabel       matlab.ui.control.Label
        SemimajoraxisSlider            matlab.ui.control.Slider
        SemiminoraxisSliderLabel       matlab.ui.control.Label
        SemiminoraxisSlider            matlab.ui.control.Slider
        InletMetalSliderLabel          matlab.ui.control.Label
        InletMetalSlider               matlab.ui.control.Slider
        SSangleSliderLabel             matlab.ui.control.Label
        LESSangleSlider                matlab.ui.control.Slider
        PSangleSliderLabel             matlab.ui.control.Label
        LEPSangleSlider                matlab.ui.control.Slider
        TrailingEdgeParametersLabel    matlab.ui.control.Label
        InletMetalSliderLabel_2        matlab.ui.control.Label
        OutletMetalSlider              matlab.ui.control.Slider
        SSangleSliderLabel_2           matlab.ui.control.Label
        TESSangleSlider                matlab.ui.control.Slider
        PSangleSliderLabel_2           matlab.ui.control.Label
        TEPSangleSlider                matlab.ui.control.Slider
        SemimajoraxisSliderLabel_2     matlab.ui.control.Label
        Radius                         matlab.ui.control.Slider
        ProfileparametersLabel         matlab.ui.control.Label
        StaggerSliderLabel             matlab.ui.control.Label
        StaggerSlider                  matlab.ui.control.Slider
        scSliderLabel                  matlab.ui.control.Label
        scSlider                       matlab.ui.control.Slider
        ocSliderLabel                  matlab.ui.control.Label
        ocSlider                       matlab.ui.control.Slider
        tcSliderLabel                  matlab.ui.control.Label
        tcSlider                       matlab.ui.control.Slider
        Label_11                       matlab.ui.control.Label
        phi_throat_Slider              matlab.ui.control.Slider
        Label_12                       matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        phi_thick_Slider               matlab.ui.control.Slider
        tLabel                         matlab.ui.control.Label
        LogdetailsTextAreaLabel        matlab.ui.control.Label
        LogM                           matlab.ui.control.TextArea
        BladeProfile_plot              matlab.ui.control.UIAxes
        TorC                           matlab.ui.control.Switch
        aEditFieldLabel                matlab.ui.control.Label
        aValue                         matlab.ui.control.EditField
        bEditFieldLabel                matlab.ui.control.Label
        bValue                         matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        MetalLEValue                   matlab.ui.control.EditField
        Label_14                       matlab.ui.control.Label
        ThetaLESSValue                 matlab.ui.control.EditField
        EditField_3Label               matlab.ui.control.Label
        ThetaLEPSValue                 matlab.ui.control.EditField
        REditFieldLabel                matlab.ui.control.Label
        RValue                         matlab.ui.control.EditField
        EditField_4Label               matlab.ui.control.Label
        MetalTEValue                   matlab.ui.control.EditField
        EditField_5Label               matlab.ui.control.Label
        ThetaTESSValue                 matlab.ui.control.EditField
        EditField_6Label               matlab.ui.control.Label
        ThetaTEPSValue                 matlab.ui.control.EditField
        Label_15                       matlab.ui.control.Label
        StaggerValue                   matlab.ui.control.EditField
        scEditFieldLabel               matlab.ui.control.Label
        scValue                        matlab.ui.control.EditField
        ocEditFieldLabel               matlab.ui.control.Label
        ocValue                        matlab.ui.control.EditField
        tcEditFieldLabel               matlab.ui.control.Label
        tcValue                        matlab.ui.control.EditField
        EditField_8Label               matlab.ui.control.Label
        PhioValue                      matlab.ui.control.EditField
        EditField_9Label               matlab.ui.control.Label
        PhitValue                      matlab.ui.control.EditField
        Label_16                       matlab.ui.control.Label
        LESSLabel                      matlab.ui.control.Label
        LEPSLabel                      matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        TESSLabel                      matlab.ui.control.Label
        TEPSLabel                      matlab.ui.control.Label
        tLabel_2                       matlab.ui.control.Label
        oLabel                         matlab.ui.control.Label
        HelpButton_Manual              matlab.ui.control.Button
        SaveProfileButton_Manual       matlab.ui.control.Button
        MisesFileEditFieldLabel_2      matlab.ui.control.Label
        MisesFileInputs_Manual         matlab.ui.control.EditField
        HelpButton_Manual_2            matlab.ui.control.Button
    end


    properties (Access = public)
            FileName_T = CreateLogFile('Turbine');
            FileName_C = CreateLogFile('Compressor');
            FileName_MT = CreateLogFile('Manual Turbine');
            FileName_MC = CreateLogFile('Manual Compressor');
    end

    methods (Access = private)
        
        function plotting_T(app)
            % Save angles
            InletMetalAngle = app.InletMetalAngleSlider_T.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_T.Value;
            Stagger = app.StaggerAngleSlider_T.Value;
              
            % Obtain Camber Line
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger, 'T');

            % Obtain extra plots
            Extras = PlottingExtras(InletMetalAngle, OutletMetalAngle, Stagger, Camber(2,1001));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            Curve2 = Extras{5};
            Curve3 = Extras{6};
            
            % Emergency messages - check them
%             if InletCamberAngle - Stagger < 0
%                message = 'Inlet Camber Angle is significantly small and/or Stagger is significantly large. Please, consider changing these values!';
%                uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
%             end
            
%             if OutletCamberAngle + Stagger >= 90
%                message = 'Outlet Camber Angle and/or is significantly large. Please, consider changing these values!';
%                uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
%             end
            
            % Obtain plots
                % Camber Line
            plot(app.CamberAxes_T,Camber(1,:),Camber(2,:))
            hold(app.CamberAxes_T,'on')
%              pan(app.CamberAxes_T,'on');
%             zoom(app.CamberAxes_T,'on');
                % Inlet Metal Arc
            plot(app.CamberAxes_T,Curve(1,:),Curve(2,:))
            hold(app.CamberAxes_T,'on')
                % Outlet Metal Arc
            plot(app.CamberAxes_T,Curve2(1,:),Curve2(2,:))
            hold(app.CamberAxes_T,'on')
                % Tangent line at inlet
            plot(app.CamberAxes_T,Line1(1,:),Line1(2,:))
            hold(app.CamberAxes_T,'on')
                % Horizontal line at outlet
            plot(app.CamberAxes_T,Line2(1,:),Line2(2,:))
            hold(app.CamberAxes_T,'on')
                % Tangent line at outlet
            plot(app.CamberAxes_T,Line3(1,:),Line3(2,:))
            hold(app.CamberAxes_T,'on')
                % Camber Angle Arc
            plot(app.CamberAxes_T,Curve3(1,:),Curve3(2,:))
            hold(app.CamberAxes_T,'off')
                % Print angles symbols
            t1 = text(app.CamberAxes_T,Curve(1,500)-0.03,Curve(2,500)-0.03,'\chi_{1}','HorizontalAlignment','left' );
            t2 = text(app.CamberAxes_T,Curve2(1,500)+0.03,Curve2(2,500)-0.03,'\chi_{2}','HorizontalAlignment','left' );
            t3 = text(app.CamberAxes_T,Curve3(1,1000)+0.03,Curve3(2,1000)-0.03,'\theta','HorizontalAlignment','left' );
            a1 = 14;
            a3 = 'bold';
            a4 = 'red';
            t1.FontSize = a1;
            t1.FontWeight = a3;
            t1.Color = a4;
            t2.FontSize = a1;
            t2.FontWeight = a3;
            t2.Color = a4;
            t3.FontSize = a1;
            t3.FontWeight = a3;
            t3.Color = a4;
        end
        
        function plotting_C(app)
            % Save angles
            InletMetalAngle = app.InletMetalAngleSlider_C.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_C.Value;
            Stagger = app.StaggerAngleSlider_C.Value;
            
            
            % CamberLine
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger, 'C');
            
            % Obtain extra plots
            Extras = PlottingExtrasC(InletMetalAngle, OutletMetalAngle, Stagger, Camber(2,1001));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            Curve2 = Extras{5};
            Curve3 = Extras{6};
            
            % Plots
            plot(app.CamberAxes_C,Camber(1,:),Camber(2,:))
            hold(app.CamberAxes_C,'on')
            % Inlet Metal Arc
            plot(app.CamberAxes_C,Curve(1,:),Curve(2,:))
            hold(app.CamberAxes_C,'on')
                % Outlet Metal Arc
            plot(app.CamberAxes_C,Curve2(1,:),Curve2(2,:))
            hold(app.CamberAxes_C,'on')
                % Tangent line at inlet
            plot(app.CamberAxes_C,Line1(1,:),Line1(2,:))
            hold(app.CamberAxes_C,'on')
                % Horizontal line at outlet
            plot(app.CamberAxes_C,Line2(1,:),Line2(2,:))
            hold(app.CamberAxes_C,'on')
                % Tangent line at outlet
            plot(app.CamberAxes_C,Line3(1,:),Line3(2,:))
            hold(app.CamberAxes_C,'on')
                % Camber Angle Arc
            plot(app.CamberAxes_C,Curve3(1,:),Curve3(2,:))
            hold(app.CamberAxes_C,'off')
            
             % Print angles symbols
            t1 = text(app.CamberAxes_C,Curve(1,500)-0.03,Curve(2,500)-0.03,'\chi_{1}','HorizontalAlignment','left' );
            t2 = text(app.CamberAxes_C,Curve2(1,500)+0.03,Curve2(2,500)-0.03,'\chi_{2}','HorizontalAlignment','left' );
            t3 = text(app.CamberAxes_C,Curve3(1,1000)+0.03,Curve3(2,1000)-0.03,'\theta','HorizontalAlignment','left' );
            a1 = 14;
            a3 = 'bold';
            a4 = 'red';
            t1.FontSize = a1;
            t1.FontWeight = a3;
            t1.Color = a4;
            t2.FontSize = a1;
            t2.FontWeight = a3;
            t2.Color = a4;
            t3.FontSize = a1;
            t3.FontWeight = a3;
            t3.Color = a4;
        end
        
        function plotting2_T(app)
            % Load Angles
            InletMetalAngle = app.InletMetalAngleSlider_T.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_T.Value;
            Stagger = app.StaggerAngleSlider_T.Value;
            
            
            % Get Camber and Blade Profiles
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger,'T');
            Blade = BladeProfile(app.ThicknessOptions_T.Value, app.MaxThicknessValue_T.Value, Camber(1,:), Camber(2,:), Stagger, 'T');
            
            % Plot
            plot(app.BladeAxes_T,Blade{1,:},Blade{2,:})
            hold(app.BladeAxes_T,'on')
            plot(app.BladeAxes_T,Camber(1,:),Camber(2,:))
            hold(app.BladeAxes_T,'off')
%             pan(app.BladeAxes_T,'on');
%             zoom(app.BladeAxes_T,'on');
            plot(app.ThicknessAxes_T,Blade{3,:},Blade{4,:})
%             pan(app.ThicknessAxes_T,'on');
%             zoom(app.ThicknessAxes_T,'on');
        end
        
        function plotting2_C(app)
            % Load Angles
            InletMetalAngle = app.InletMetalAngleSlider_C.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_C.Value;
            Stagger = app.StaggerAngleSlider_C.Value;
            
            
            % Get Camber and Blade Profiles
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger,'C');
            Blade = BladeProfile(app.ThicknessOptions_T.Value, app.MaxThicknessValue_C.Value, Camber(1,:), Camber(2,:), Stagger, 'C');
            
            % Plot
            plot(app.BladeAxes_C,Blade{1,:},Blade{2,:})
            hold(app.BladeAxes_C,'on')
            plot(app.BladeAxes_C,Camber(1,:),Camber(2,:))
            hold(app.BladeAxes_C,'off')
%             pan(app.BladeAxes_T,'on');
%             zoom(app.BladeAxes_T,'on');
            plot(app.ThicknessAxes_C,Blade{3,:},Blade{4,:})
%             pan(app.ThicknessAxes_T,'on');
%             zoom(app.ThicknessAxes_T,'on');
        end
        
        function values_angles_T(app)
            Metal1 = app.InletMetalAngleSlider_T.Value;
            Metal2 = app.OutletMetalAngleSlider_T.Value;
            Stagger = app.StaggerAngleSlider_T.Value;
            
            Camber1 = Metal1 + Stagger;
            Camber2 = Metal2 - Stagger;
            
            app.Camber1Value_T.Value = strcat(num2str(Camber1),char(176));
            
            app.Camber2Value_T.Value = strcat(num2str(Camber2),char(176));
            
            CamberAngle = Metal1+Metal2;
            app.CamberAngleValue_T.Value = strcat(num2str(CamberAngle),char(176));
        end
        
        function values_angles_C(app)
            Metal1 = app.InletMetalAngleSlider_C.Value;
            Metal2 = app.OutletMetalAngleSlider_C.Value;
            Stagger = app.StaggerAngleSlider_C.Value;
            
            Camber1 = - Metal1 + Stagger;
            Camber2 = Metal2 - Stagger;
            
            app.Camber1Value_C.Value = strcat(num2str(Camber1),char(176));
            
            app.Camber2Value_C.Value = strcat(num2str(Camber2),char(176));
            
            CamberAngle = Camber1 + Camber2;
            app.CamberAngleValue_C.Value = strcat(num2str(CamberAngle),char(176));
        end
        
        function thickness_T(app)
            Options = importdata('./ThicknessProfiles/ThicknessProfiles.dat');
            app.ThicknessOptions_T.Items = Options;
            thickness2_T(app)
        end
        
        function thickness_C(app)
            Options = importdata('./ThicknessProfiles/ThicknessProfiles.dat');
            app.ThicknessOptions_C.Items = Options;
            thickness2_C(app)
        end
            
        function thickness2_T(app)
            Max_T = MaxThickness(app.ThicknessOptions_T.Value);
            app.MaximumThicknessProfile_T.Value = Max_T(2);
            if app.MaxThicknessSlider_T.Value<0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_T.Value);
            elseif app.MaxThicknessSlider_T.Value>0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_T.Value);
            else
                text = '0%';
            end
            app.MaxThicknessSliderValue_T.Value = text;
            app.MaxThicknessValue_T.Value = (1+app.MaxThicknessSlider_T.Value/100)*Max_T(2)/100;
            plotting_T(app);
            plotting2_T(app);
        end

        function thickness2_C(app)
            Max_T = MaxThickness(app.ThicknessOptions_C.Value);
            app.MaximumThicknessProfile_C.Value = Max_T(2);
            if app.MaxThicknessSlider_C.Value<0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_C.Value);
            elseif app.MaxThicknessSlider_C.Value>0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_C.Value);
            else
                text = '0%';
            end
            app.MaxThicknessSliderValue_C.Value = text;
            app.MaxThicknessValue_C.Value = (1+app.MaxThicknessSlider_C.Value/100)*Max_T(2)/100;
            plotting_C(app);
            plotting2_C(app);
        end
        
        function LogUpload_T(app)
           fileID = fopen(app.FileName_T);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.Log_T.Value = Value;
           app.Log_C.Value = Value;

        end
        
        function LogUpload_C(app)
           fileID = fopen(app.FileName_C);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.Log_C.Value = Value;

        end
        
        function LogUpload_MT(app)
           fileID = fopen(app.FileName_MT);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.LogM.Value = Value;

        end
        
        function LogUpload_MC(app)
           fileID = fopen(app.FileName_MC);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.LogM.Value = Value;

        end
        
        function plotting_manual(app)
            %% Load LE Parameters
            a = app.SemimajoraxisSlider.Value;
            b = app.SemiminoraxisSlider.Value;
            Metal1 = app.InletMetalSlider.Value;
            phi_LE_SS = app.LESSangleSlider.Value;
            phi_LE_PS = app.LEPSangleSlider.Value;
            
            %% Load TE Parameters
            Metal2 = app.OutletMetalSlider.Value;
            phi_TE_SS = app.TESSangleSlider.Value;
            phi_TE_PS = app.TEPSangleSlider.Value;
            R_TE = app.Radius.Value;
            
            %% Load Profile Parameters Value
            Stagger = app.StaggerSlider.Value;
            s_c = app.scSlider.Value;
            o_c = app.ocSlider.Value;
            t_c = app.tcSlider.Value;
            phi_o = app.phi_throat_Slider.Value;
            phi_t = app.phi_thick_Slider.Value;
            
            %%----
            if isequal(app.TorC.Value, 'Turbine')
                Option = 'T';
            else
                Option = 'C';
            end
            
            [ LE, Camber, TE, Curve1, Curve2, Curve3, Curve4, CP] = BladeManual( Option, Metal1, Metal2, Stagger, a, b, phi_LE_SS, phi_LE_PS, phi_TE_SS, phi_TE_PS, R_TE, s_c, o_c, t_c, phi_o, phi_t );
            plot(app.BladeProfile_plot, LE(1,:), LE(2,:), 'LineWidth',1.5)
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, TE(1,:), TE(2,:), 'LineWidth',1.5)
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, Camber(1,:), Camber(2,:),'-.g')
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, Curve1(1,:), Curve1(2,:), '-m', 'LineWidth',1.5)
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, Curve2(1,:), Curve2(2,:), '-m', 'LineWidth',1.5)
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, Curve3(1,:), Curve3(2,:), '-m', 'LineWidth',1.5)
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, Curve4(1,:), Curve4(2,:), '-m', 'LineWidth',1.5)
            hold(app.BladeProfile_plot, 'on')
            plot(app.BladeProfile_plot, CP(1,1), CP(1,2), 'or', CP(2,1), CP(2,2), 'or', CP(3,1), CP(3,2), 'or', CP(4,1), CP(4,2), 'or', CP(5,1), CP(5,2), 'or', CP(6,1), CP(6,2), 'or' , CP(7,1), CP(7,2), 'or', CP(8,1), CP(8,2), 'or', CP(9,1), CP(9,2), 'or', CP(10,1), CP(10,2), 'or', CP(11,1), CP(11,2), 'or')
            hold(app.BladeProfile_plot, 'off')
        end
end      

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Options_T = importdata('./ThicknessProfiles/ThicknessProfiles.dat');
            app.ThicknessOptions_T.Items = Options_T;
            app.ThicknessOptions_T.Value = app.ThicknessOptions_T.Items{1};
            thickness2_T(app);
            
            Options_C = importdata('./ThicknessProfiles/ThicknessProfiles.dat');
            app.ThicknessOptions_C.Items = Options_C;
            app.ThicknessOptions_C.Value = app.ThicknessOptions_C.Items{1};
            thickness2_C(app);
        end

        % Button pushed function: AddthickprofileButton_C
        function AddthickprofileButton_CPushed(app, event)
            run('ThicknessProfile.mlapp');
        end

        % Button pushed function: AddthickprofileButton_T
        function AddthickprofileButton_TPushed(app, event)
            run('ThicknessProfile.mlapp');
        end

        % Button pushed function: DeleteprofileButton_C
        function DeleteprofileButton_CPushed(app, event)
            ProfileName = app.ThicknessOptions_C.Value;
            Message = DeleteProfile(ProfileName);
            uialert(app.BladeDesigner,Message,'File uploaded','Icon','../Images/ok.png')
            thickness_C(app)
        end

        % Button pushed function: DeleteprofileButton_T
        function DeleteprofileButton_TPushed(app, event)
            type = 'Turbine';
            ProfileName = app.ThicknessOptions_T.Value;
            Message = DeleteProfile(ProfileName);
            uialert(app.BladeDesigner,Message,'File uploaded','Icon','./Images/ok.png')
            thickness_T(app)
        end

        % Button pushed function: HelpButton_C
        function HelpButton_CPushed(app, event)
            message = sprintf('%s\n%s\n%s','Introduce Mises Inputs in the following way','Blade Name ; Flow inlet angle (degrees) ; Inlet Mach ; Inlet Re Number ; pitch ratio','Example: Blade01 ; 65 ; 0.45 ; 150000 ; 1.00');
            uialert(app.BladeDesigner,message,'Please take into account...','Icon','./Images/help.png');
        end

        % Button pushed function: HelpButton_Manual
        function HelpButton_ManualPushed(app, event)
            if isequal(app.TorC.Value, 'Turbine')
               message = sprintf('%s\n%s\n%s','Introduce Mises Inputs in the following way','Blade Name ; Flow inlet angle (degrees) ; Outlet Mach ; Inlet Re Number','Example: Blade01 ; 20 ; 0.45 ; 150000');
            else
               message = sprintf('%s\n%s\n%s','Introduce Mises Inputs in the following way','Blade Name ; Flow inlet angle (degrees) ; Inlet Mach ; Inlet Re Number','Example: Blade01 ; 65 ; 0.45 ; 150000');
            end
               uialert(app.BladeDesigner,message,'Please take into account...','Icon','./Images/help.png');
            
        end

        % Button pushed function: HelpButton_Manual_2
        function HelpButton_Manual_2Pushed(app, event)
            system('display ManualDesignParameters.jpg');
        end

        % Button pushed function: HelpButton_T
        function HelpButton_TPushed(app, event)
            message = sprintf('%s\n%s\n%s','Introduce Mises Inputs in the following way','Blade Name ; Flow inlet angle (degrees) ; Outlet Mach ; Inlet Re Number ; pitch ratio','Example: Blade01 ; 20 ; 0.45 ; 150000 ; 1.00');
            uialert(app.BladeDesigner,message,'Please take into account...','Icon','./Images/help.png');
        end

        % Value changed function: InletMetalAngleSlider_C
        function InletMetalAngleSlider_CValueChanged(app, event)
            InletMetalAngle_C = app.InletMetalAngleSlider_C.Value;
            app.InletMetalAngleValue_C.Value = strcat(num2str(InletMetalAngle_C), char(176));
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue_C.Value)];
            plotting_C(app);
            plotting2_C(app);
            thickness_C(app);
            values_angles_C(app);
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: InletMetalAngleSlider_T
        function InletMetalAngleSlider_TValueChanged(app, event)
            InletMetalAngle = app.InletMetalAngleSlider_T.Value;
            app.InletMetalAngleValue_T.Value = strcat(num2str(InletMetalAngle), char(176));
            Text = ['Inlet Metal Angle has been changed to ',app.InletMetalAngleValue_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);   
            plotting_T(app)
            plotting2_T(app)
            values_angles_T(app)
            thickness_T(app)
        end

        % Value changed function: InletMetalSlider
        function InletMetalSliderValueChanged(app, event)
            plotting_manual(app);
            app.MetalLEValue.Value = [num2str(app.InletMetalSlider.Value), char(176)];
            Text = ['Semimajor axis length has been changed to ',app.MetalLEValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: LEPSangleSlider
        function LEPSangleSliderValueChanged(app, event)
            plotting_manual(app);
            app.ThetaLEPSValue.Value = [num2str(app.LEPSangleSlider.Value) char(176)];
            Text = ['Pressure Surface LE θ angle has been changed to ',app.ThetaLEPSValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: LESSangleSlider
        function LESSangleSliderValueChanged(app, event)
            plotting_manual(app);
            app.ThetaLESSValue.Value = [num2str(app.LESSangleSlider.Value) char(176)];
            Text = ['Suction Surface LE θ angle has been changed to ',app.ThetaLESSValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
            
        end

        % Value changed function: MaxThicknessSliderValue_C
        function MaxThicknessSliderValue_CValueChanged(app, event)
            thickness_C(app);
            
        end

        % Value changed function: MaxThicknessSliderValue_T
        function MaxThicknessSliderValue_TValueChanged(app, event)
            thickness_T(app)
        end

        % Value changed function: MaxThicknessSlider_C
        function MaxThicknessSlider_CValueChanged(app, event)
            thickness_C(app)
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue_C.Value)];
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: MaxThicknessSlider_T
        function MaxThicknessSlider_TValueChanged(app, event)
            thickness_T(app)
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue_T.Value)];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);   
        end

        % Value changed function: OutletMetalAngleSlider_C
        function OutletMetalAngleSlider_CValueChanged(app, event)
            OutletMetalAngle = app.OutletMetalAngleSlider_C.Value;
            app.OutletMetalAngleValue_C.Value = strcat(num2str(OutletMetalAngle), char(176));
            Text = ['Outlet Metal Angle has been changed to ',app.OutletMetalAngleValue_C.Value];
            plotting_C(app);
            plotting2_C(app);
            thickness_C(app);
            values_angles_C(app);
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: OutletMetalAngleSlider_T
        function OutletMetalAngleSlider_TValueChanged(app, event)
            OutletMetalAngle = app.OutletMetalAngleSlider_T.Value;
            app.OutletMetalAngleValue_T.Value = strcat(num2str(OutletMetalAngle), char(176));
            Text = ['Outlet Metal Angle has been changed to ',app.OutletMetalAngleValue_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);
            plotting_T(app)
            plotting2_T(app)
            values_angles_T(app)
            thickness_T(app)
        end

        % Value changed function: OutletMetalSlider
        function OutletMetalSliderValueChanged(app, event)
            plotting_manual(app);
            app.MetalTEValue.Value = [num2str(app.OutletMetalSlider.Value) char(176)];
            Text = ['Outlet Metal angle has been changed to ',app.MetalTEValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: Radius
        function RadiusValueChanged(app, event)
            plotting_manual(app);
            app.RValue.Value = num2str(app.Radius.Value);
            Text = ['TE circunference radius has been changed to ',app.RValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Button pushed function: SaveProfileButton_C
        function SaveProfileButton_CPushed(app, event)
            Inp = app.MisesFileInputs_C.Value;
            [Var, error] = MISES_save(Inp);
            BladeProf = load('./Blade/BladeC.dat');
            Stagger = app.StaggerAngleSlider_C.Value;
            Metal1 = app.InletMetalAngleSlider_C.Value;
            Metal2 = app.OutletMetalAngleSlider_C.Value;
            if error == 1
                message = sprintf('%s\n%s\n%s\n%s','Please, write the inputs properly. Remember...','Introduce Mises Inputs in the following way','Blade Name ; Flow inlet angle (degrees) ; Inlet Mach ; Inlet Re Number ; pitch ratio','Example: Blade01 ; 65 ; 0.45 ; 150000 ; 1.00');
                uialert(app.BladeDesigner,message,'Please take into account...','Icon','./Images/error.png');
            else
                MISES_files(Var{1}, 'C', Metal1, Metal2, str2num(Var{5}), Stagger, str2num(Var{2}), 0, str2num(Var{4}), str2num(Var{3}), BladeProf,2);
                uialert(app.BladeDesigner,'MISES files properly created','File uploaded','Icon','./Images/ok.png')
                uiwait(app.BladeDesigner,3)
                app.MisesFileInputs_C.Value = '';
                choice = questdlg('Do you want to analyse the blade shape?', 'MISES', 'Yes', 'No', 'No');
                if isequal(choice, 'No')
                    return;
                else
                    uiwait(msgbox('Now, you will have to choose MISES route. Please, be sure that MISES is properly compiled; otherwise, an error message will be retrieved','MISES information'));
                    cont = 0;
                    while cont==0
                        route = uigetdir('', 'Select MISES route');
                        if route==0
                            route = uigetdir('', 'Select MISES route');
                        else
                            break;
                        end
                    end
                    MisesRun(Var{1}, route);
                    [status, result] = system(['./allMISES.scp ' Var{1}]);
                    if status == 1
                        warndlg(result, 'Warning');
                    else
                        msgbox('Results files properly created')
                        cd('..');
                        cd('..');
                        uiwait(msgbox('Please, select the routh where you want to have MISES results','Turbomachinery Blade Designer information'));
                        results_route = uigetdir('', 'Select results route');
                       copyfile(['Mises_Files/Mises_',Var{1},'/bl.',Var{1}],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/field.',Var{1}],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Cf.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Mis.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Cp.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/PS.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/SS.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/resultados.txt'],results_route);
                        msgbox('Process ended!')
                    end
                end
            end
        end

        % Button pushed function: SaveProfileButton_Manual
        function SaveProfileButton_ManualPushed(app, event)
            Option = app.TorC.Value;
            if isequal(Option, 'Turbine')
                Opt = 'T';
            else
                Opt = 'C';
            end
            Inp = app.MisesFileInputs_Manual.Value;
            Inp = [Inp, ';',num2str(app.scSlider.Value)];
            [Var, error] = MISES_save(Inp);
            Stagger = app.StaggerSlider.Value;
            Metal1 = app.InletMetalSlider.Value;
            Metal2 = app.OutletMetalSlider.Value;
            BladeProf = load(['./Blade/Blade', Opt,'.dat']);
            if error == 1
                message = sprintf('%s\n%s\n%s\n%s','Please, write the inputs properly');
                uialert(app.BladeDesigner,message,'Please take into account...','Icon','./Images/error.png');
            else
                MISES_files(Var{1}, Opt, Metal1, Metal2, str2num(Var{5}), Stagger, str2num(Var{2}), str2num(Var{3}), str2num(Var{4}), str2num(Var{3}), BladeProf,3);
                uialert(app.BladeDesigner,'MISES files properly created','File uploaded','Icon','./Images/ok.png')
                uiwait(app.BladeDesigner,3)
                app.MisesFileInputs_Manual.Value = '';
                choice = questdlg('Do you want to analyse the blade shape?', 'MISES', 'Yes', 'No', 'No');
                if isequal(choice, 'No')
                    return;
                else
                    uiwait(msgbox('Now, you will have to choose MISES route. Please, be sure that MISES is properly compiled; otherwise, an error message will be retrieved','MISES information'));
                    cont = 0;
                    while cont==0
                        route = uigetdir('', 'Select MISES route');
                        if route==0
                            route = uigetdir('', 'Select MISES route');
                        else
                            cont = 1;
                            break;
                        end
                    end
                    MisesRun(Var{1}, route);
                    [status, result] = system(['./allMISES.scp ' Var{1}]);
                    if status == 1
                        warndlg(result, 'Warning');
                    else
                        msgbox('Results files properly created')
                        cd('..');
                        cd('..');
                        uiwait(msgbox('Please, select the routh where you want to have MISES results','Turbomachinery Blade Designer information'));
                        results_route = uigetdir('', 'Select results route');
                       copyfile(['Mises_Files/Mises_',Var{1},'/bl.',Var{1}],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/field.',Var{1}],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Cf.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Mis.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Cp.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/PS.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/SS.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/resultados.txt'],results_route);
                        msgbox('Process ended!')
                    end
                end
            end
        end

        % Button pushed function: SaveProfileButton_T
        function SaveProfileButton_TPushed(app, event)
            Inp = app.MisesFileInputs_T.Value;
            [Var, error] = MISES_save(Inp);
            Stagger = app.StaggerAngleSlider_T.Value;
            Metal1 = app.InletMetalAngleSlider_T.Value;
            Metal2 = app.OutletMetalAngleSlider_T.Value;
            BladeProf = load('./Blade/BladeT.dat');
            if error == 1
                message = sprintf('%s\n%s\n%s\n%s','Please, write the inputs properly. Remember...','Introduce Mises Inputs in the following way','Blade Name ; Flow inlet angle (degrees) ; Outlet Mach ; Inlet Re Number ; pitch ratio','Example: Blade01 ; 20 ; 0.45 ; 150000 ; 1.00');
                uialert(app.BladeDesigner,message,'Please take into account...','Icon','./Images/error.png');
            else
                MISES_files(Var{1}, 'T', Metal1, Metal2, str2num(Var{5}), Stagger, str2num(Var{2}), str2num(Var{3}), str2num(Var{4}), 0, BladeProf,1);
                uialert(app.BladeDesigner,'MISES files properly created','File uploaded','Icon','./Images/ok.png')
                uiwait(app.BladeDesigner,3)
                app.MisesFileInputs_T.Value = '';
                choice = questdlg('Do you want to analyse the blade shape?', 'MISES', 'Yes', 'No', 'No');
                if isequal(choice, 'No')
                    return;
                else
                    uiwait(msgbox('Now, you will have to choose MISES route. Please, be sure that MISES is properly compiled; otherwise, an error message will be retrieved','MISES information'));
                    cont = 0;
                    while cont==0
                        route = uigetdir('', 'Select MISES route');
                        if route==0
                            route = uigetdir('', 'Select MISES route');
                        else
                            cont = 1;
                            break;4
                        end
                    end
                    MisesRun(Var{1}, route);
                    [status, result] = system(['./allMISES.scp ' Var{1}]);
                    if status == 1
                        warndlg(result, 'Warning');
                    else
                        msgbox('Results files properly created')
                        cd('..');
                        cd('..');
                        uiwait(msgbox('Please, select the routh where you want to have MISES results','Turbomachinery Blade Designer information'));
                        results_route = uigetdir('', 'Select results route');
                        copyfile(['Mises_Files/Mises_',Var{1},'/bl.',Var{1}],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/field.',Var{1}],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Cf.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Mis.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/Cp.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/PS.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/SS.ps'],results_route);
                        copyfile(['Mises_Files/Mises_',Var{1},'/resultados.txt'],results_route);
                        msgbox('Process ended!')
                    end
                end
            end
        end

        % Value changed function: SemimajoraxisSlider
        function SemimajoraxisSliderValueChanged(app, event)
            plotting_manual(app);
            app.aValue.Value = [num2str(app.SemimajoraxisSlider.Value)];
            Text = ['Semimajor axis length has been changed to ',app.aValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: SemiminoraxisSlider
        function SemiminoraxisSliderValueChanged(app, event)
            plotting_manual(app);
            app.bValue.Value = [num2str(app.SemiminoraxisSlider.Value)];
            Text = ['Semiminor axis length has been changed to ',app.bValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: StaggerAngleSlider_C
        function StaggerAngleSlider_CValueChanged(app, event)
            Stagger = app.StaggerAngleSlider_C.Value;
            app.StaggerAngleValue_C.Value = strcat(num2str(Stagger), char(176));
            Text = ['Stagger Angle has been changed to ',app.StaggerAngleValue_C.Value];
            plotting_C(app);
            plotting2_C(app);
            thickness_C(app);
            values_angles_C(app);
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: StaggerAngleSlider_T
        function StaggerAngleSlider_TValueChanged(app, event)
            Stagger = app.StaggerAngleSlider_T.Value;
            app.StaggerAngleValue_T.Value = strcat(num2str(Stagger), char(176));
            Text = ['Stagger Angle has been changed to ',app.StaggerAngleValue_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);
            plotting_T(app)
            plotting2_T(app)
            values_angles_T(app)
            thickness_T(app)
        end

        % Value changed function: StaggerSlider
        function StaggerSliderValueChanged(app, event)
            plotting_manual(app);
            app.StaggerValue.Value = [num2str(app.StaggerSlider.Value) char(176)];
            Text = ['Stagger angle has been changed to ',app.StaggerValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: TEPSangleSlider
        function TEPSangleSliderValueChanged(app, event)
            plotting_manual(app);
            app.ThetaTEPSValue.Value = [num2str(app.TEPSangleSlider.Value) char(176)];
            Text = ['Pressure Surface TE θ angle has been changed to ',app.ThetaTEPSValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: TESSangleSlider
        function TESSangleSliderValueChanged(app, event)
            plotting_manual(app);
            app.ThetaTESSValue.Value = [num2str(app.TESSangleSlider.Value) char(176)];
            Text = ['Suction Surface TE θ angle has been changed to ',app.ThetaTESSValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: ThicknessOptions_C
        function ThicknessOptions_CValueChanged(app, event)
            plotting_C(app)
            plotting2_C(app)
            thickness_C(app)
            Text = ['Thickness Profile has been changed to ',app.ThicknessOptions_C.Value];
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: ThicknessOptions_T
        function ThicknessOptions_TValueChanged(app, event)
            plotting_T(app)
            plotting2_T(app)
            thickness_T(app)
            Text = ['Thickness Profile has been changed to ',app.ThicknessOptions_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);
        end

        % Value changed function: ocSlider
        function ocSliderValueChanged(app, event)
            plotting_manual(app);
            app.ocValue.Value = num2str(app.ocSlider.Value);
            Text = ['Throat to chord radio has been changed to ',app.ocValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: phi_thick_Slider
        function phi_thick_SliderValueChanged(app, event)
            plotting_manual(app);
            app.PhitValue.Value = [num2str(app.phi_thick_Slider.Value) char(176)];
            Text = ['Thickness θ angle has been changed to ',app.PhitValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: phi_throat_Slider
        function phi_throat_SliderValueChanged(app, event)
            plotting_manual(app);
            app.PhioValue.Value = [num2str(app.phi_throat_Slider.Value) char(176)];
            Text = ['Throat θ angle has been changed to ',app.PhioValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: scSlider
        function scSliderValueChanged(app, event)
            plotting_manual(app);
            app.scValue.Value  = num2str(app.scSlider.Value);
            Text = ['Pitch to chord radio has been changed to ',app.scValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end

        % Value changed function: tcSlider
        function tcSliderValueChanged(app, event)
            plotting_manual(app);
            app.tcValue.Value = num2str(app.tcSlider.Value);
            Text = ['Thickness to chord radio has been changed to ',app.tcValue.Value];
            if isequal(app.TorC.Value, 'Turbine')
                LogFile(app.FileName_MT, Text);
                LogUpload_MT(app);
            else
                LogFile(app.FileName_MC, Text);
                LogUpload_MC(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesigner = uifigure;
            app.BladeDesigner.Name = 'Blade Designer';
            app.TurbomachineryBladeDesignerLabel = uilabel(app.BladeDesigner);
            app.TurbomachineryBladeDesignerLabel.Text = 'Turbomachinery Blade Designer';
            app.Selection = uitabgroup(app.BladeDesigner);
            app.Turbine = uitab(app.Selection);
            app.Turbine.Title = 'Turbine';
            app.CamberAxes_T = uiaxes(app.Turbine);
            title(app.CamberAxes_T, 'Camber Line')
            xlabel(app.CamberAxes_T, 'X')
            ylabel(app.CamberAxes_T, 'Y')
            app.CamberAxes_T.XAxisLocation = 'origin';
            app.CamberAxes_T.YAxisLocation = 'origin';
            app.ThicknessAxes_T = uiaxes(app.Turbine);
            title(app.ThicknessAxes_T, 'Thickness Profile')
            xlabel(app.ThicknessAxes_T, 'X')
            ylabel(app.ThicknessAxes_T, 'Y')
            app.ThicknessAxes_T.XAxisLocation = 'origin';
            app.ThicknessAxes_T.YAxisLocation = 'origin';
            app.BladeAxes_T = uiaxes(app.Turbine);
            title(app.BladeAxes_T, 'Blade Profile')
            xlabel(app.BladeAxes_T, 'X')
            ylabel(app.BladeAxes_T, 'Y')
            app.BladeAxes_T.XAxisLocation = 'origin';
            app.BladeAxes_T.XTick = [0 0.5 1 1.5 2];
            app.BladeAxes_T.YAxisLocation = 'origin';
            app.BladeAxes_T.YTick = [-0.5 0 0.5 1 1.5];
            app.InletMetalAngleSlider_T = uislider(app.Turbine);
            app.InletMetalAngleSlider_T.Limits = [0 90];
            app.InletMetalAngleSlider_T.ValueChangedFcn = createCallbackFcn(app, @InletMetalAngleSlider_TValueChanged, true);
            app.OutletMetalAngleSliderLabel_T = uilabel(app.Turbine);
            app.OutletMetalAngleSliderLabel_T.Text = 'Outlet Metal Angle';
            app.OutletMetalAngleSlider_T = uislider(app.Turbine);
            app.OutletMetalAngleSlider_T.Limits = [0 90];
            app.OutletMetalAngleSlider_T.ValueChangedFcn = createCallbackFcn(app, @OutletMetalAngleSlider_TValueChanged, true);
            app.InletMetalAngleSliderLabel_T = uilabel(app.Turbine);
            app.InletMetalAngleSliderLabel_T.Text = 'Inlet Metal Angle';
            app.StaggerAngleSlider_T = uislider(app.Turbine);
            app.StaggerAngleSlider_T.Limits = [0 90];
            app.StaggerAngleSlider_T.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSlider_TValueChanged, true);
            app.StaggerAngleSliderLabel_T = uilabel(app.Turbine);
            app.StaggerAngleSliderLabel_T.Text = 'Stagger Angle';
            app.ChoosebladethicknessprofileLabel_T = uilabel(app.Turbine);
            app.ChoosebladethicknessprofileLabel_T.Text = 'Choose blade thickness profile';
            app.LogdetailsLabel_T = uilabel(app.Turbine);
            app.LogdetailsLabel_T.Text = 'Log details';
            app.ThicknessOptions_T = uilistbox(app.Turbine);
            app.ThicknessOptions_T.Items = {};
            app.ThicknessOptions_T.ValueChangedFcn = createCallbackFcn(app, @ThicknessOptions_TValueChanged, true);
            app.ThicknessOptions_T.Value = {};
            app.InletMetalAngleValue_T = uieditfield(app.Turbine, 'text');
            app.InletMetalAngleValue_T.Editable = 'off';
            app.OutletMetalAngleValue_T = uieditfield(app.Turbine, 'text');
            app.OutletMetalAngleValue_T.Editable = 'off';
            app.StaggerAngleValue_T = uieditfield(app.Turbine, 'text');
            app.StaggerAngleValue_T.Editable = 'off';
            app.AddthickprofileButton_T = uibutton(app.Turbine, 'push');
            app.AddthickprofileButton_T.ButtonPushedFcn = createCallbackFcn(app, @AddthickprofileButton_TPushed, true);
            app.AddthickprofileButton_T.Text = 'Add thick profile';
            app.CamberLineAnglesLabel_T = uilabel(app.Turbine);
            app.CamberLineAnglesLabel_T.Text = 'Camber Line Angles';
            app.Label = uilabel(app.Turbine);
            app.Label.Text = 'θ';
            app.Camber1Value_T = uieditfield(app.Turbine, 'text');
            app.Camber1Value_T.Editable = 'off';
            app.Label_3 = uilabel(app.Turbine);
            app.Label_3.Text = 'θ';
            app.Camber2Value_T = uieditfield(app.Turbine, 'text');
            app.Camber2Value_T.Editable = 'off';
            app.Label_2 = uilabel(app.Turbine);
            app.Label_2.Text = 'θ';
            app.CamberAngleValue_T = uieditfield(app.Turbine, 'text');
            app.CamberAngleValue_T.Editable = 'off';
            app.Label_4 = uilabel(app.Turbine);
            app.Label_4.Text = '1';
            app.Label_5 = uilabel(app.Turbine);
            app.Label_5.Text = '2';
            app.DeleteprofileButton_T = uibutton(app.Turbine, 'push');
            app.DeleteprofileButton_T.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButton_TPushed, true);
            app.DeleteprofileButton_T.Text = 'Delete profile';
            app.IncreaseordecreasethemaximumthicknessLabel_T = uilabel(app.Turbine);
            app.IncreaseordecreasethemaximumthicknessLabel_T.Text = 'Increase or decrease the maximum thickness';
            app.MaximumThicknessoftheProfileLabel = uilabel(app.Turbine);
            app.MaximumThicknessoftheProfileLabel.Text = 'Maximum Thickness of the Profile (%)';
            app.MaximumThicknessProfile_T = uieditfield(app.Turbine, 'numeric');
            app.MaximumThicknessProfile_T.Editable = 'off';
            app.MaxThicknessSlider_T = uislider(app.Turbine);
            app.MaxThicknessSlider_T.Limits = [-100 100];
            app.MaxThicknessSlider_T.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSlider_TValueChanged, true);
            app.MaxThicknessValue_T = uieditfield(app.Turbine, 'numeric');
            app.MaxThicknessValue_T.ValueDisplayFormat = '%.3f';
            app.MaxThicknessValue_T.Editable = 'off';
            app.MaxThicknessSliderValue_T = uieditfield(app.Turbine, 'text');
            app.MaxThicknessSliderValue_T.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSliderValue_TValueChanged, true);
            app.MaxThicknessSliderValue_T.Editable = 'off';
            app.Log_T = uitextarea(app.Turbine);
            app.Log_T.Editable = 'off';
            app.Log_T.Value = {''; ''};
            app.MisesFileEditFieldLabel = uilabel(app.Turbine);
            app.MisesFileEditFieldLabel.Text = 'Mises File';
            app.MisesFileInputs_T = uieditfield(app.Turbine, 'text');
            app.HelpButton_T = uibutton(app.Turbine, 'push');
            app.HelpButton_T.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_TPushed, true);
            app.HelpButton_T.Icon = 'help.png';
            app.HelpButton_T.Text = '';
            app.SaveProfileButton_T = uibutton(app.Turbine, 'push');
            app.SaveProfileButton_T.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButton_TPushed, true);
            app.SaveProfileButton_T.Icon = 'save.png';
            app.SaveProfileButton_T.Text = '';
            app.CompressorTab = uitab(app.Selection);
            app.CompressorTab.Title = 'Compressor';
            app.CamberAxes_C = uiaxes(app.CompressorTab);
            title(app.CamberAxes_C, 'Camber Line')
            xlabel(app.CamberAxes_C, 'X')
            ylabel(app.CamberAxes_C, 'Y')
            app.CamberAxes_C.CLim = [0 1];
            app.CamberAxes_C.CLimMode = 'manual';
            app.CamberAxes_C.XAxisLocation = 'origin';
            app.CamberAxes_C.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CamberAxes_C.YAxisLocation = 'origin';
            app.CamberAxes_C.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ThicknessAxes_C = uiaxes(app.CompressorTab);
            title(app.ThicknessAxes_C, 'Thickness Profile')
            xlabel(app.ThicknessAxes_C, 'X')
            ylabel(app.ThicknessAxes_C, 'Y')
            app.ThicknessAxes_C.CLim = [0 1];
            app.ThicknessAxes_C.CLimMode = 'manual';
            app.ThicknessAxes_C.XAxisLocation = 'origin';
            app.ThicknessAxes_C.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ThicknessAxes_C.YAxisLocation = 'origin';
            app.ThicknessAxes_C.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.BladeAxes_C = uiaxes(app.CompressorTab);
            title(app.BladeAxes_C, 'Blade Profile')
            xlabel(app.BladeAxes_C, 'X')
            ylabel(app.BladeAxes_C, 'Y')
            app.BladeAxes_C.CLim = [0 1];
            app.BladeAxes_C.CLimMode = 'manual';
            app.BladeAxes_C.XAxisLocation = 'origin';
            app.BladeAxes_C.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.BladeAxes_C.YAxisLocation = 'origin';
            app.BladeAxes_C.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.InletMetalAngleSlider_C = uislider(app.CompressorTab);
            app.InletMetalAngleSlider_C.Limits = [0 90];
            app.InletMetalAngleSlider_C.ValueChangedFcn = createCallbackFcn(app, @InletMetalAngleSlider_CValueChanged, true);
            app.OutletMetalAngleSliderLabel_C = uilabel(app.CompressorTab);
            app.OutletMetalAngleSliderLabel_C.Text = 'Outlet Metal Angle';
            app.OutletMetalAngleSlider_C = uislider(app.CompressorTab);
            app.OutletMetalAngleSlider_C.Limits = [0 90];
            app.OutletMetalAngleSlider_C.ValueChangedFcn = createCallbackFcn(app, @OutletMetalAngleSlider_CValueChanged, true);
            app.InletMetalAngleSliderLabel_C = uilabel(app.CompressorTab);
            app.InletMetalAngleSliderLabel_C.Text = 'Inlet Metal Angle';
            app.StaggerAngleSlider_C = uislider(app.CompressorTab);
            app.StaggerAngleSlider_C.Limits = [0 90];
            app.StaggerAngleSlider_C.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSlider_CValueChanged, true);
            app.StaggerAngleSliderLabel_C = uilabel(app.CompressorTab);
            app.StaggerAngleSliderLabel_C.Text = 'Stagger Angle';
            app.InletMetalAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.InletMetalAngleValue_C.Editable = 'off';
            app.OutletMetalAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.OutletMetalAngleValue_C.Editable = 'off';
            app.StaggerAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.StaggerAngleValue_C.Editable = 'off';
            app.CamberLineAnglesLabel_C = uilabel(app.CompressorTab);
            app.CamberLineAnglesLabel_C.Text = 'Camber Line Angles';
            app.Label_6 = uilabel(app.CompressorTab);
            app.Label_6.Text = 'θ';
            app.Camber1Value_C = uieditfield(app.CompressorTab, 'text');
            app.Camber1Value_C.Editable = 'off';
            app.Label_7 = uilabel(app.CompressorTab);
            app.Label_7.Text = 'θ';
            app.Camber2Value_C = uieditfield(app.CompressorTab, 'text');
            app.Camber2Value_C.Editable = 'off';
            app.Label_8 = uilabel(app.CompressorTab);
            app.Label_8.Text = 'θ';
            app.CamberAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.CamberAngleValue_C.Editable = 'off';
            app.ChoosebladethicknessprofileLabel_C = uilabel(app.CompressorTab);
            app.ChoosebladethicknessprofileLabel_C.Text = 'Choose blade thickness profile';
            app.ThicknessOptions_C = uilistbox(app.CompressorTab);
            app.ThicknessOptions_C.Items = {};
            app.ThicknessOptions_C.ValueChangedFcn = createCallbackFcn(app, @ThicknessOptions_CValueChanged, true);
            app.ThicknessOptions_C.Value = {};
            app.AddthickprofileButton_C = uibutton(app.CompressorTab, 'push');
            app.AddthickprofileButton_C.ButtonPushedFcn = createCallbackFcn(app, @AddthickprofileButton_CPushed, true);
            app.AddthickprofileButton_C.Text = 'Add thick profile';
            app.DeleteprofileButton_C = uibutton(app.CompressorTab, 'push');
            app.DeleteprofileButton_C.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButton_CPushed, true);
            app.DeleteprofileButton_C.Text = 'Delete profile';
            app.IncreaseordecreasethemaximumthicknessLabel_C = uilabel(app.CompressorTab);
            app.IncreaseordecreasethemaximumthicknessLabel_C.Text = 'Increase or decrease the maximum thickness';
            app.MaximumThicknessoftheProfileLabel_C = uilabel(app.CompressorTab);
            app.MaximumThicknessoftheProfileLabel_C.Text = 'Maximum Thickness of the Profile (%)';
            app.MaximumThicknessProfile_C = uieditfield(app.CompressorTab, 'numeric');
            app.MaximumThicknessProfile_C.Editable = 'off';
            app.MaxThicknessSlider_C = uislider(app.CompressorTab);
            app.MaxThicknessSlider_C.Limits = [-100 100];
            app.MaxThicknessSlider_C.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSlider_CValueChanged, true);
            app.MaxThicknessValue_C = uieditfield(app.CompressorTab, 'numeric');
            app.MaxThicknessValue_C.ValueDisplayFormat = '%.3f';
            app.MaxThicknessValue_C.Editable = 'off';
            app.MaxThicknessSliderValue_C = uieditfield(app.CompressorTab, 'text');
            app.MaxThicknessSliderValue_C.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSliderValue_CValueChanged, true);
            app.MaxThicknessSliderValue_C.Editable = 'off';
            app.LogdetailsLabel_C = uilabel(app.CompressorTab);
            app.LogdetailsLabel_C.Text = 'Log details';
            app.Log_C = uitextarea(app.CompressorTab);
            app.Log_C.Editable = 'off';
            app.Log_C.Value = {''; ''};
            app.Label_9 = uilabel(app.CompressorTab);
            app.Label_9.Text = '1';
            app.Label_10 = uilabel(app.CompressorTab);
            app.Label_10.Text = '2';
            app.HelpButton_C = uibutton(app.CompressorTab, 'push');
            app.HelpButton_C.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_CPushed, true);
            app.HelpButton_C.Icon = 'help.png';
            app.HelpButton_C.Text = '';
            app.SaveProfileButton_C = uibutton(app.CompressorTab, 'push');
            app.SaveProfileButton_C.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButton_CPushed, true);
            app.SaveProfileButton_C.Icon = 'save.png';
            app.SaveProfileButton_C.Text = '';
            app.MisesFileEditField_2Label = uilabel(app.CompressorTab);
            app.MisesFileEditField_2Label.Text = 'Mises File';
            app.MisesFileInputs_C = uieditfield(app.CompressorTab, 'text');
            app.ManualTab = uitab(app.Selection);
            app.ManualTab.Title = 'Manual';
            app.LeadingEdgeParametersLabel = uilabel(app.ManualTab);
            app.LeadingEdgeParametersLabel.Text = 'Leading Edge Parameters';
            app.SemimajoraxisSliderLabel = uilabel(app.ManualTab);
            app.SemimajoraxisSliderLabel.Text = 'Semimajor axis';
            app.SemimajoraxisSlider = uislider(app.ManualTab);
            app.SemimajoraxisSlider.Limits = [0 0.2];
            app.SemimajoraxisSlider.MajorTicks = [0 0.1 0.2];
            app.SemimajoraxisSlider.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisSliderValueChanged, true);
            app.SemiminoraxisSliderLabel = uilabel(app.ManualTab);
            app.SemiminoraxisSliderLabel.Text = 'Semiminor axis';
            app.SemiminoraxisSlider = uislider(app.ManualTab);
            app.SemiminoraxisSlider.Limits = [0 0.1];
            app.SemiminoraxisSlider.ValueChangedFcn = createCallbackFcn(app, @SemiminoraxisSliderValueChanged, true);
            app.InletMetalSliderLabel = uilabel(app.ManualTab);
            app.InletMetalSliderLabel.Text = 'Inlet Metal';
            app.InletMetalSlider = uislider(app.ManualTab);
            app.InletMetalSlider.Limits = [0 90];
            app.InletMetalSlider.ValueChangedFcn = createCallbackFcn(app, @InletMetalSliderValueChanged, true);
            app.SSangleSliderLabel = uilabel(app.ManualTab);
            app.SSangleSliderLabel.Text = 'SS angle';
            app.LESSangleSlider = uislider(app.ManualTab);
            app.LESSangleSlider.Limits = [0 180];
            app.LESSangleSlider.ValueChangedFcn = createCallbackFcn(app, @LESSangleSliderValueChanged, true);
            app.PSangleSliderLabel = uilabel(app.ManualTab);
            app.PSangleSliderLabel.Text = 'PS angle';
            app.LEPSangleSlider = uislider(app.ManualTab);
            app.LEPSangleSlider.Limits = [0 180];
            app.LEPSangleSlider.ValueChangedFcn = createCallbackFcn(app, @LEPSangleSliderValueChanged, true);
            app.TrailingEdgeParametersLabel = uilabel(app.ManualTab);
            app.TrailingEdgeParametersLabel.Text = 'Trailing Edge Parameters';
            app.InletMetalSliderLabel_2 = uilabel(app.ManualTab);
            app.InletMetalSliderLabel_2.Text = 'Outlet Metal';
            app.OutletMetalSlider = uislider(app.ManualTab);
            app.OutletMetalSlider.Limits = [0 90];
            app.OutletMetalSlider.ValueChangedFcn = createCallbackFcn(app, @OutletMetalSliderValueChanged, true);
            app.SSangleSliderLabel_2 = uilabel(app.ManualTab);
            app.SSangleSliderLabel_2.Text = 'SS angle';
            app.TESSangleSlider = uislider(app.ManualTab);
            app.TESSangleSlider.Limits = [0 180];
            app.TESSangleSlider.ValueChangedFcn = createCallbackFcn(app, @TESSangleSliderValueChanged, true);
            app.PSangleSliderLabel_2 = uilabel(app.ManualTab);
            app.PSangleSliderLabel_2.Text = 'PS angle';
            app.TEPSangleSlider = uislider(app.ManualTab);
            app.TEPSangleSlider.Limits = [0 180];
            app.TEPSangleSlider.ValueChangedFcn = createCallbackFcn(app, @TEPSangleSliderValueChanged, true);
            app.SemimajoraxisSliderLabel_2 = uilabel(app.ManualTab);
            app.SemimajoraxisSliderLabel_2.Text = 'Radius';
            app.Radius = uislider(app.ManualTab);
            app.Radius.Limits = [0 0.005];
            app.Radius.ValueChangedFcn = createCallbackFcn(app, @RadiusValueChanged, true);
            app.ProfileparametersLabel = uilabel(app.ManualTab);
            app.ProfileparametersLabel.Text = 'Profile parameters';
            app.StaggerSliderLabel = uilabel(app.ManualTab);
            app.StaggerSliderLabel.Text = 'Stagger';
            app.StaggerSlider = uislider(app.ManualTab);
            app.StaggerSlider.Limits = [0 90];
            app.StaggerSlider.ValueChangedFcn = createCallbackFcn(app, @StaggerSliderValueChanged, true);
            app.scSliderLabel = uilabel(app.ManualTab);
            app.scSliderLabel.Text = 's/c';
            app.scSlider = uislider(app.ManualTab);
            app.scSlider.Limits = [0 1.5];
            app.scSlider.ValueChangedFcn = createCallbackFcn(app, @scSliderValueChanged, true);
            app.ocSliderLabel = uilabel(app.ManualTab);
            app.ocSliderLabel.Text = 'o/c';
            app.ocSlider = uislider(app.ManualTab);
            app.ocSlider.Limits = [0 1];
            app.ocSlider.ValueChangedFcn = createCallbackFcn(app, @ocSliderValueChanged, true);
            app.tcSliderLabel = uilabel(app.ManualTab);
            app.tcSliderLabel.Text = 't/c';
            app.tcSlider = uislider(app.ManualTab);
            app.tcSlider.Limits = [0 1];
            app.tcSlider.ValueChangedFcn = createCallbackFcn(app, @tcSliderValueChanged, true);
            app.Label_11 = uilabel(app.ManualTab);
            app.Label_11.Text = 'φ';
            app.phi_throat_Slider = uislider(app.ManualTab);
            app.phi_throat_Slider.Limits = [0 90];
            app.phi_throat_Slider.ValueChangedFcn = createCallbackFcn(app, @phi_throat_SliderValueChanged, true);
            app.Label_12 = uilabel(app.ManualTab);
            app.Label_12.Text = 'ο';
            app.Label_13 = uilabel(app.ManualTab);
            app.Label_13.Text = 'φ';
            app.phi_thick_Slider = uislider(app.ManualTab);
            app.phi_thick_Slider.Limits = [0 90];
            app.phi_thick_Slider.ValueChangedFcn = createCallbackFcn(app, @phi_thick_SliderValueChanged, true);
            app.tLabel = uilabel(app.ManualTab);
            app.tLabel.Text = 't';
            app.LogdetailsTextAreaLabel = uilabel(app.ManualTab);
            app.LogdetailsTextAreaLabel.Text = 'Log details';
            app.LogM = uitextarea(app.ManualTab);
            app.BladeProfile_plot = uiaxes(app.ManualTab);
            title(app.BladeProfile_plot, 'Blade profile')
            xlabel(app.BladeProfile_plot, 'X')
            ylabel(app.BladeProfile_plot, 'Y')
            app.BladeProfile_plot.XAxisLocation = 'origin';
            app.BladeProfile_plot.YAxisLocation = 'origin';
            app.TorC = uiswitch(app.ManualTab, 'slider');
            app.TorC.Items = {'Turbine', 'Compressor'};
            app.TorC.Value = 'Turbine';
            app.aEditFieldLabel = uilabel(app.ManualTab);
            app.aEditFieldLabel.Text = 'a';
            app.aValue = uieditfield(app.ManualTab, 'text');
            app.aValue.Editable = 'off';
            app.bEditFieldLabel = uilabel(app.ManualTab);
            app.bEditFieldLabel.Text = 'b';
            app.bValue = uieditfield(app.ManualTab, 'text');
            app.bValue.Editable = 'off';
            app.EditFieldLabel = uilabel(app.ManualTab);
            app.EditFieldLabel.Text = 'χ';
            app.MetalLEValue = uieditfield(app.ManualTab, 'text');
            app.MetalLEValue.Editable = 'off';
            app.Label_14 = uilabel(app.ManualTab);
            app.Label_14.Text = 'φ';
            app.ThetaLESSValue = uieditfield(app.ManualTab, 'text');
            app.ThetaLESSValue.Editable = 'off';
            app.EditField_3Label = uilabel(app.ManualTab);
            app.EditField_3Label.Text = 'φ';
            app.ThetaLEPSValue = uieditfield(app.ManualTab, 'text');
            app.ThetaLEPSValue.Editable = 'off';
            app.REditFieldLabel = uilabel(app.ManualTab);
            app.REditFieldLabel.Text = 'R';
            app.RValue = uieditfield(app.ManualTab, 'text');
            app.RValue.Editable = 'off';
            app.EditField_4Label = uilabel(app.ManualTab);
            app.EditField_4Label.Text = 'χ';
            app.MetalTEValue = uieditfield(app.ManualTab, 'text');
            app.MetalTEValue.Editable = 'off';
            app.EditField_5Label = uilabel(app.ManualTab);
            app.EditField_5Label.Text = 'φ';
            app.ThetaTESSValue = uieditfield(app.ManualTab, 'text');
            app.ThetaTESSValue.Editable = 'off';
            app.EditField_6Label = uilabel(app.ManualTab);
            app.EditField_6Label.Text = 'φ';
            app.ThetaTEPSValue = uieditfield(app.ManualTab, 'text');
            app.ThetaTEPSValue.Editable = 'off';
            app.Label_15 = uilabel(app.ManualTab);
            app.Label_15.Text = 'ς';
            app.StaggerValue = uieditfield(app.ManualTab, 'text');
            app.StaggerValue.Editable = 'off';
            app.scEditFieldLabel = uilabel(app.ManualTab);
            app.scEditFieldLabel.Text = 's/c';
            app.scValue = uieditfield(app.ManualTab, 'text');
            app.scValue.Editable = 'off';
            app.ocEditFieldLabel = uilabel(app.ManualTab);
            app.ocEditFieldLabel.Text = 'o/c';
            app.ocValue = uieditfield(app.ManualTab, 'text');
            app.ocValue.Editable = 'off';
            app.tcEditFieldLabel = uilabel(app.ManualTab);
            app.tcEditFieldLabel.Text = 't/c';
            app.tcValue = uieditfield(app.ManualTab, 'text');
            app.tcValue.Editable = 'off';
            app.EditField_8Label = uilabel(app.ManualTab);
            app.EditField_8Label.Text = 'φ';
            app.PhioValue = uieditfield(app.ManualTab, 'text');
            app.PhioValue.Editable = 'off';
            app.EditField_9Label = uilabel(app.ManualTab);
            app.EditField_9Label.Text = 'φ';
            app.PhitValue = uieditfield(app.ManualTab, 'text');
            app.PhitValue.Editable = 'off';
            app.Label_16 = uilabel(app.ManualTab);
            app.Label_16.Text = '1';
            app.LESSLabel = uilabel(app.ManualTab);
            app.LESSLabel.Text = 'LE,SS';
            app.LEPSLabel = uilabel(app.ManualTab);
            app.LEPSLabel.Text = 'LE,PS';
            app.Label_17 = uilabel(app.ManualTab);
            app.Label_17.Text = '2';
            app.TESSLabel = uilabel(app.ManualTab);
            app.TESSLabel.Text = 'TE,SS';
            app.TEPSLabel = uilabel(app.ManualTab);
            app.TEPSLabel.Text = 'TE,PS';
            app.tLabel_2 = uilabel(app.ManualTab);
            app.tLabel_2.Text = 't';
            app.oLabel = uilabel(app.ManualTab);
            app.oLabel.Text = 'o';
            app.HelpButton_Manual = uibutton(app.ManualTab, 'push');
            app.HelpButton_Manual.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_ManualPushed, true);
            app.HelpButton_Manual.Icon = 'help.png';
            app.HelpButton_Manual.Text = '';
            app.SaveProfileButton_Manual = uibutton(app.ManualTab, 'push');
            app.SaveProfileButton_Manual.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButton_ManualPushed, true);
            app.SaveProfileButton_Manual.Icon = 'save.png';
            app.SaveProfileButton_Manual.Text = '';
            app.MisesFileEditFieldLabel_2 = uilabel(app.ManualTab);
            app.MisesFileEditFieldLabel_2.Text = 'Mises File';
            app.MisesFileInputs_Manual = uieditfield(app.ManualTab, 'text');
            app.HelpButton_Manual_2 = uibutton(app.ManualTab, 'push');
            app.HelpButton_Manual_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Manual_2Pushed, true);
            app.HelpButton_Manual_2.Icon = 'help.png';
            app.HelpButton_Manual_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BladeDesignerBeta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesigner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesigner)
        end
    end
end