classdef ThicknessProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThicknessProfileFigure          matlab.ui.Figure
        AddyourthiknessProfileLabel     matlab.ui.control.Label
        ProfilenameEditFieldLabel       matlab.ui.control.Label
        ProfilenameEditField            matlab.ui.control.EditField
        EnteryourprofileEditFieldLabel  matlab.ui.control.Label
        EnteryourprofileEditField       matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        WritebelowyourthicknessProfileasfollowLabel  matlab.ui.control.Label
        SeparatecolumnswithblankspacesLabel  matlab.ui.control.Label
        SeparaterowswithsemicolonLabel  matlab.ui.control.Label
        IfthethicknessprofileisnotproperlywrittenLabel  matlab.ui.control.Label
        anerrormessagewillbedisplayedLabel  matlab.ui.control.Label
        WritebelowyourthicknessProfileasfollowLabel_2  matlab.ui.control.Label
        WritebelowyourthicknessProfileasfollowLabel_3  matlab.ui.control.Label
        SaveProfileButton               matlab.ui.control.Button
        TypeProfile                     matlab.ui.control.Switch
    end


    methods (Access = private)
   
        
        function profile(app)
            [Message, x, t] = Register(app.EnteryourprofileEditField.Value);
            if isequal(Message,"")
               Name = app.ProfilenameEditField.Value;
               if isempty(Name)
                   uialert(app.ThicknessProfileFigure, 'Please, insert the profile name','Error Window','Icon','.\Images\error.png')
               else
                   Type = app.TypeProfile.Value;
                   Message = SaveProfile(x,t,Type,Name);
                   uialert(app.ThicknessProfileFigure,Message,'File uploaded','Icon','.\Images\ok.png')
               end   
            else
                [n, ~] = size(Message);
                TextMessage = Message{2};
                if n>2
                    for i = 3:n
                       TextMessage = sprintf('%s\n%s\n',TextMessage,Message{i});
                    end
                end
                uialert(app.ThicknessProfileFigure,TextMessage,'Error Window','Icon','.\Images\error.png');
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Example:\n 0 10 30 50 80 100; 0.0 0.87 1.25 1.50 0.47 0.0');
            uialert(app.ThicknessProfileFigure,message,'Please take into account...','Icon','.\Images\help.png');
        end

        % Button pushed function: SaveProfileButton
        function SaveProfileButtonPushed(app, event)
            profile(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThicknessProfileFigure = uifigure;
            app.ThicknessProfileFigure.Name = 'Add Thickness Profile';
            app.AddyourthiknessProfileLabel = uilabel(app.ThicknessProfileFigure);
            app.AddyourthiknessProfileLabel.Text = 'Add your thikness Profile';
            app.ProfilenameEditFieldLabel = uilabel(app.ThicknessProfileFigure);
            app.ProfilenameEditFieldLabel.Text = 'Profile name';
            app.ProfilenameEditField = uieditfield(app.ThicknessProfileFigure, 'text');
            app.EnteryourprofileEditFieldLabel = uilabel(app.ThicknessProfileFigure);
            app.EnteryourprofileEditFieldLabel.Text = 'Enter your profile';
            app.EnteryourprofileEditField = uieditfield(app.ThicknessProfileFigure, 'text');
            app.HelpButton = uibutton(app.ThicknessProfileFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'help.png';
            app.HelpButton.Text = '';
            app.WritebelowyourthicknessProfileasfollowLabel = uilabel(app.ThicknessProfileFigure);
            app.WritebelowyourthicknessProfileasfollowLabel.Text = 'Write below your thickness Profile as follow:';
            app.SeparatecolumnswithblankspacesLabel = uilabel(app.ThicknessProfileFigure);
            app.SeparatecolumnswithblankspacesLabel.Text = '- Separate columns with blank spaces';
            app.SeparaterowswithsemicolonLabel = uilabel(app.ThicknessProfileFigure);
            app.SeparaterowswithsemicolonLabel.Text = '- Separate rows with semicolon ;';
            app.IfthethicknessprofileisnotproperlywrittenLabel = uilabel(app.ThicknessProfileFigure);
            app.IfthethicknessprofileisnotproperlywrittenLabel.Text = 'If the thickness profile is not properly written,';
            app.anerrormessagewillbedisplayedLabel = uilabel(app.ThicknessProfileFigure);
            app.anerrormessagewillbedisplayedLabel.Text = 'an error message will be displayed';
            app.WritebelowyourthicknessProfileasfollowLabel_2 = uilabel(app.ThicknessProfileFigure);
            app.WritebelowyourthicknessProfileasfollowLabel_2.Text = 'Coordinates need to be written as percentages';
            app.WritebelowyourthicknessProfileasfollowLabel_3 = uilabel(app.ThicknessProfileFigure);
            app.WritebelowyourthicknessProfileasfollowLabel_3.Text = 'and over the chord length';
            app.SaveProfileButton = uibutton(app.ThicknessProfileFigure, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileButton.Icon = 'save.png';
            app.SaveProfileButton.Text = '';
            app.TypeProfile = uiswitch(app.ThicknessProfileFigure, 'slider');
            app.TypeProfile.Items = {'Turbine', 'Compressor'};
            app.TypeProfile.Value = 'Turbine';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ThicknessProfile

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThicknessProfileFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThicknessProfileFigure)
        end
    end
end