classdef home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BladeDesignerBeta10vLabel     matlab.ui.control.Label
        InletCamberAngleSliderLabel   matlab.ui.control.Label
        InletCamberAngleSlider        matlab.ui.control.Slider
        OutletCamberAngleSliderLabel  matlab.ui.control.Label
        OutletCamberAngleSlider       matlab.ui.control.Slider
        StaggerAngleSliderLabel       matlab.ui.control.Label
        StaggerAngleSlider            matlab.ui.control.Slider
        InletCamberAngleValue         matlab.ui.control.NumericEditField
        OutletCamberAngleValue        matlab.ui.control.NumericEditField
        StaggerAngleValue             matlab.ui.control.NumericEditField
        degreesLabel                  matlab.ui.control.Label
        degreesLabel_2                matlab.ui.control.Label
        degreesLabel_3                matlab.ui.control.Label
        CamberLinePlot                matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function plotting(app)
            InletCamberAngle = app.InletCamberAngleSlider.Value;
            OutletCamberAngle = app.OutletCamberAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            Camber = CamberLine(InletCamberAngle,OutletCamberAngle,Stagger);

            
            Extras = PlottingExtras(InletCamberAngle, OutletCamberAngle, Stagger, Camber(4,1000));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            
            if InletCamberAngle - Stagger < 0
               message = 'Inlet Camber Angle is significantly small and/or Stagger is significantly large. Please, consider changing these values!';
               uialert(app.UIFigure,message,'Recommendation','Icon','info');
            end
            
%             if OutletCamberAngle - Stagger < 0
%                message = 'Outlet Camber Angle is significantly small and/or Stagger is significantly large. Please, consider changing these values!';
%                uialert(app.UIFigure,message,'Recommendation','Icon','info');
%             end
            plot(app.CamberLinePlot,Camber(3,:),Camber(4,:))
            hold(app.CamberLinePlot,'on')
            plot(app.CamberLinePlot,Curve(1,:),Curve(2,:))
            hold(app.CamberLinePlot,'on')
            plot(app.CamberLinePlot,Line1(1,:),Line1(2,:))
            hold(app.CamberLinePlot,'on')
            plot(app.CamberLinePlot,Line2(1,:),Line2(2,:))
            hold(app.CamberLinePlot,'on')
            plot(app.CamberLinePlot,Line3(1,:),Line3(2,:))
            hold(app.CamberLinePlot,'off')
            text(app.CamberLinePlot,-0.15,-0.03,[int2str(InletCamberAngle - Stagger), char(176)],'HorizontalAlignment','left' );
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: InletCamberAngleSlider
        function InletCamberAngleSliderValueChanged(app, event)
            InletCamberAngle = app.InletCamberAngleSlider.Value;
            app.InletCamberAngleValue.Value = InletCamberAngle;
            plotting(app);
        end

        % Value changed function: OutletCamberAngleSlider
        function OutletCamberAngleSliderValueChanged(app, event)
            OutletCamberAngle = app.OutletCamberAngleSlider.Value;
            app.OutletCamberAngleValue.Value = OutletCamberAngle;
            plotting(app);
        end

        % Value changed function: StaggerAngleSlider
        function StaggerAngleSliderValueChanged(app, event)
            Stagger = app.StaggerAngleSlider.Value;
            app.StaggerAngleValue.Value = Stagger;
            plotting(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BladeDesignerBeta10vLabel = uilabel(app.UIFigure);
            app.BladeDesignerBeta10vLabel.Text = 'Blade Designer Beta 1.0v';
            app.InletCamberAngleSliderLabel = uilabel(app.UIFigure);
            app.InletCamberAngleSliderLabel.Text = 'Inlet Camber Angle';
            app.InletCamberAngleSlider = uislider(app.UIFigure);
            app.InletCamberAngleSlider.Limits = [0 90];
            app.InletCamberAngleSlider.ValueChangedFcn = createCallbackFcn(app, @InletCamberAngleSliderValueChanged, true);
            app.OutletCamberAngleSliderLabel = uilabel(app.UIFigure);
            app.OutletCamberAngleSliderLabel.Text = 'Outlet Camber Angle';
            app.OutletCamberAngleSlider = uislider(app.UIFigure);
            app.OutletCamberAngleSlider.Limits = [0 90];
            app.OutletCamberAngleSlider.ValueChangedFcn = createCallbackFcn(app, @OutletCamberAngleSliderValueChanged, true);
            app.StaggerAngleSliderLabel = uilabel(app.UIFigure);
            app.StaggerAngleSliderLabel.Text = 'Stagger Angle';
            app.StaggerAngleSlider = uislider(app.UIFigure);
            app.StaggerAngleSlider.Limits = [0 90];
            app.StaggerAngleSlider.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSliderValueChanged, true);
            app.InletCamberAngleValue = uieditfield(app.UIFigure, 'numeric');
            app.InletCamberAngleValue.Editable = 'off';
            app.OutletCamberAngleValue = uieditfield(app.UIFigure, 'numeric');
            app.OutletCamberAngleValue.Editable = 'off';
            app.StaggerAngleValue = uieditfield(app.UIFigure, 'numeric');
            app.StaggerAngleValue.Editable = 'off';
            app.degreesLabel = uilabel(app.UIFigure);
            app.degreesLabel.Text = 'degrees';
            app.degreesLabel_2 = uilabel(app.UIFigure);
            app.degreesLabel_2.Text = 'degrees';
            app.degreesLabel_3 = uilabel(app.UIFigure);
            app.degreesLabel_3.Text = 'degrees';
            app.CamberLinePlot = uiaxes(app.UIFigure);
            title(app.CamberLinePlot, 'Camber Line')
            xlabel(app.CamberLinePlot, 'x')
            ylabel(app.CamberLinePlot, 'y')
            app.CamberLinePlot.CLim = [0 1];
            app.CamberLinePlot.CLimMode = 'manual';
            app.CamberLinePlot.XAxisLocation = 'origin';
            app.CamberLinePlot.XTick = [0 0.5 1];
            app.CamberLinePlot.YAxisLocation = 'origin';
            app.CamberLinePlot.YTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = home

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end